classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Panel                 matlab.ui.container.Panel
        ValCheckBox           matlab.ui.control.CheckBox
        XvalDropDown          matlab.ui.control.DropDown
        YvalDropDown          matlab.ui.control.DropDown
        LoadNetDropDown       matlab.ui.control.DropDown
        LoadNetDropDownLabel  matlab.ui.control.Label
        XteDropDown           matlab.ui.control.DropDown
        XteDropDownLabel      matlab.ui.control.Label
        YteDropDown           matlab.ui.control.DropDown
        YteDropDownLabel      matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        TestButton            matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        TestPanel             matlab.ui.container.Panel
        YtrDropDown           matlab.ui.control.DropDown
        YtrDropDownLabel      matlab.ui.control.Label
        XtrDropDown           matlab.ui.control.DropDown
        XtrDropDownLabel      matlab.ui.control.Label
        wEditField            matlab.ui.control.EditField
        wEditFieldLabel       matlab.ui.control.Label
        LEditField            matlab.ui.control.NumericEditField
        LEditFieldLabel       matlab.ui.control.Label
        deltaEditField        matlab.ui.control.NumericEditField
        deltaEditFieldLabel   matlab.ui.control.Label
        epochEditField        matlab.ui.control.NumericEditField
        epochEditFieldLabel   matlab.ui.control.Label
        alphaEditField        matlab.ui.control.NumericEditField
        alphaEditFieldLabel   matlab.ui.control.Label
        TrainButton           matlab.ui.control.Button
        StopButton_2          matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            workspaceVars = evalin('base', 'who');
            app.YtrDropDown.Items = workspaceVars;
            app.XtrDropDown.Items = workspaceVars;
            app.XteDropDown.Items = workspaceVars;
            app.YteDropDown.Items = workspaceVars;
            app.StopButton_2.Tag = '0';
            sizeWend = size(evalin('base', app.YtrDropDown.Value),2);
            val = ones(1,app.LEditField.Value-1)*3*sizeWend;
            app.wEditField.Value = ['[',num2str([val,sizeWend]),']'];
            app.XvalDropDown.Items = workspaceVars;
            app.YvalDropDown.Items = workspaceVars;
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            sizeWend = size(evalin('base', app.YtrDropDown.Value),2);
            val = ones(1,app.LEditField.Value-1)*3*sizeWend;
            app.wEditField.Value = ['[',num2str([val,sizeWend]),']'];
            app.wEditField.Enable = "on";
        end

        % Value changed function: YtrDropDown
        function YtrDropDownValueChanged(app, event)
            sizeWend = size(evalin('base', app.YtrDropDown.Value),2);
            val = ones(1,app.LEditField.Value-1)*3*sizeWend;
            app.wEditField.Value = ['[',num2str([val,sizeWend]),']'];
            app.wEditField.Enable = "on";
            XtrDropDownValueChanged(app)
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.TrainButton.Enable = "off";
            app.TrainButton.Text = 'Training...';
            app.TrainButton.Layout.Column = [3 5];
            app.XtrDropDown.Enable = "off";
            app.YtrDropDown.Enable = "off";
            app.LEditField.Enable = "off";
            app.wEditField.Enable = "off";
            app.deltaEditField.Enable = "off";
            app.alphaEditField.Enable = "off";
            app.epochEditField.Enable = "off";
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Xtrain = evalin('base', app.XtrDropDown.Value);
            Ytrain = evalin('base', app.YtrDropDown.Value);
            if 1
                Xval = evalin('base', app.XvalDropDown.Value);
                Yval = evalin('base', app.YvalDropDown.Value);
            end
            L = app.LEditField.Value;
            w = str2num(app.wEditField.Value);
            EPOCH = app.epochEditField.Value;

            delta = app.deltaEditField.Value;
            alpha = app.alphaEditField.Value;
            net = MSOFNN(Xtrain,Ytrain,L,w,delta,alpha);

            % TRAIN
            MSE = inf(EPOCH,1);
            if app.ValCheckBox.Value, MSEval = inf(EPOCH,1); end
            bestMSE = inf;
            for epoch = 1:EPOCH
                if str2double(app.StopButton_2.Tag), break, end

                [net, MSE(epoch)] = net.main(Xtrain, Ytrain, epoch);
                % if (epoch > 1) && (abs(MSE(epoch) - MSE(epoch-1)) < 1e-6), break, end
                
                if MSE(epoch) < bestMSE
                    bestEpoch = epoch;
                    bestMSE = MSE(epoch);
                    bestNet = net;
                end
                plot(app.UIAxes, 1:epoch, MSE(1:epoch)), hold(app.UIAxes,"on")
                scatter(app.UIAxes, bestEpoch, bestMSE, 'MarkerEdgeColor',[0 .5 .5],...
                                                        'MarkerFaceColor',[0 .7 .7],...
                                                        'LineWidth',1.5)
                if app.ValCheckBox.Value
                    [~,err] = net.test(Xval,Yval);
                    MSEval(epoch) = err.MSE;
                    plot(app.UIAxes, 1:epoch, MSEval(1:epoch), 'k')
                end
                drawnow
                hold(app.UIAxes,"off")
            end
            % save Best and Last net
            assignin('base', 'BestTrainedNet', bestNet);
            assignin('base', 'LastTrainedNet', net);
            app.LoadNetDropDown.Items = evalin('base', 'who');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.StopButton_2.Visible = "off";
            app.TrainButton.Text = 'Trained';
            app.TrainButton.Layout.Column = [3 6];
            app.TestButton.Visible = "on";
            app.XteDropDown.Visible = "on";
            app.YteDropDown.Visible = "on";
            app.TrainButton.BackgroundColor = [0.47,0.67,0.19];
            app.TrainButton.FontColor = [1 1 1];
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            app.StopButton_2.Tag = num2str(1);
        end

        % Value changed function: XtrDropDown
        function XtrDropDownValueChanged(app, event)
            app.Label.Text = "Data(" + size(evalin('base', app.XtrDropDown.Value),1)...
                + ")  Feature(" + size(evalin('base', app.XtrDropDown.Value),2) + ","+size(evalin('base', app.YtrDropDown.Value),2)+")";
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            Xtest = evalin('base', app.XteDropDown.Value);
            Ytest = evalin('base', app.YteDropDown.Value);
            net = evalin('base', app.LoadNetDropDown.Value);
            [yhat,err] = net.test(Xtest,Ytest);
            assignin('base', 'testedyhat', yhat);
            app.Label_2.Text = sprintf('MSE: %.3f \nRMSE: %.3f \nNDEI: %.3f',err.MSE,err.RMSE,err.NDEI);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Learning Plot')
            xlabel(app.UIAxes, 'EPOCH')
            ylabel(app.UIAxes, 'MSE')
            zlabel(app.UIAxes, 'Z')
            app.StopButton_2 = uibutton(app.GridLayout, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.TrainButton = uibutton(app.GridLayout, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.alphaEditFieldLabel = uilabel(app.GridLayout);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.GridLayout, 'numeric');
            app.alphaEditField.Limits = [0 Inf];
            app.alphaEditField.Tooltip = {'learning rate'};
            app.alphaEditField.Value = 1;
            app.epochEditFieldLabel = uilabel(app.GridLayout);
            app.epochEditFieldLabel.Text = 'epoch';
            app.epochEditField = uieditfield(app.GridLayout, 'numeric');
            app.epochEditField.Limits = [1 Inf];
            app.epochEditField.Tooltip = {'number of maximum epoch'};
            app.epochEditField.Value = 200;
            app.deltaEditFieldLabel = uilabel(app.GridLayout);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.GridLayout, 'numeric');
            app.deltaEditField.Limits = [0 Inf];
            app.deltaEditField.Tooltip = {'thereshold'};
            app.deltaEditField.Value = 0.0497870683678639;
            app.LEditFieldLabel = uilabel(app.GridLayout);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.GridLayout, 'numeric');
            app.LEditField.Limits = [1 Inf];
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Tooltip = {'number of layer'};
            app.LEditField.Value = 2;
            app.wEditFieldLabel = uilabel(app.GridLayout);
            app.wEditFieldLabel.Enable = 'off';
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.GridLayout, 'text');
            app.wEditField.Enable = 'off';
            app.XtrDropDownLabel = uilabel(app.GridLayout);
            app.XtrDropDownLabel.Text = 'Xtr';
            app.XtrDropDown = uidropdown(app.GridLayout);
            app.XtrDropDown.Items = {'X_train', 'Y_train', 'X_test', 'Y_test'};
            app.XtrDropDown.ValueChangedFcn = createCallbackFcn(app, @XtrDropDownValueChanged, true);
            app.XtrDropDown.Value = 'X_train';
            app.YtrDropDownLabel = uilabel(app.GridLayout);
            app.YtrDropDownLabel.Text = 'Ytr';
            app.YtrDropDown = uidropdown(app.GridLayout);
            app.YtrDropDown.Items = {'X_train', 'Y_train', 'X_test', 'Y_test'};
            app.YtrDropDown.ValueChangedFcn = createCallbackFcn(app, @YtrDropDownValueChanged, true);
            app.YtrDropDown.Value = 'Y_train';
            app.TestPanel = uipanel(app.GridLayout);
            app.TestPanel.Title = 'Test Panel';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.TestButton = uibutton(app.GridLayout, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.YteDropDownLabel = uilabel(app.GridLayout);
            app.YteDropDownLabel.Text = 'Yte';
            app.YteDropDown = uidropdown(app.GridLayout);
            app.YteDropDown.Items = {'X_train', 'Y_train', 'X_test', 'Y_test'};
            app.YteDropDown.Value = 'Y_test';
            app.XteDropDownLabel = uilabel(app.GridLayout);
            app.XteDropDownLabel.Text = 'Xte';
            app.XteDropDown = uidropdown(app.GridLayout);
            app.XteDropDown.Items = {'X_train', 'Y_train', 'X_test', 'Y_test'};
            app.XteDropDown.Value = 'X_test';
            app.LoadNetDropDownLabel = uilabel(app.GridLayout);
            app.LoadNetDropDownLabel.Text = 'Load Net';
            app.LoadNetDropDown = uidropdown(app.GridLayout);
            app.LoadNetDropDown.Items = {'LastTrainedNet', 'BestTrainedNet'};
            app.LoadNetDropDown.Value = 'LastTrainedNet';
            app.Panel = uipanel(app.GridLayout);
            app.YvalDropDown = uidropdown(app.Panel);
            app.YvalDropDown.Items = {};
            app.YvalDropDown.Value = {};
            app.XvalDropDown = uidropdown(app.Panel);
            app.XvalDropDown.Items = {};
            app.XvalDropDown.Value = {};
            app.ValCheckBox = uicheckbox(app.Panel);
            app.ValCheckBox.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end