classdef MSOFNN3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SolverEditField          matlab.ui.control.EditField
        SolverEditFieldLabel     matlab.ui.control.Label
        DropDown2                matlab.ui.control.DropDown
        DropDown2Label           matlab.ui.control.Label
        MSOFNNLabel              matlab.ui.control.Label
        PoweredbyITHENOAv1Label  matlab.ui.control.Label
        TabGroup2                matlab.ui.container.TabGroup
        TrainTab_2               matlab.ui.container.Tab
        GridLayout3              matlab.ui.container.GridLayout
        Label                    matlab.ui.control.Label
        StopButton_2             matlab.ui.control.Button
        TrainButton              matlab.ui.control.Button
        ParametersPanel          matlab.ui.container.Panel
        AFEditField              matlab.ui.control.EditField
        AFEditFieldLabel         matlab.ui.control.Label
        wEditField               matlab.ui.control.EditField
        wEditFieldLabel          matlab.ui.control.Label
        deltaEditField           matlab.ui.control.NumericEditField
        deltaEditFieldLabel      matlab.ui.control.Label
        alphaEditField           matlab.ui.control.NumericEditField
        alphaEditFieldLabel      matlab.ui.control.Label
        epochEditField           matlab.ui.control.NumericEditField
        epochEditFieldLabel      matlab.ui.control.Label
        LEditField               matlab.ui.control.NumericEditField
        LEditFieldLabel          matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        ofTraindataLabel         matlab.ui.control.Label
        OruseLabel               matlab.ui.control.Label
        oruseEditField           matlab.ui.control.NumericEditField
        YvalDropDown             matlab.ui.control.DropDown
        YtrDropDownLabel_3       matlab.ui.control.Label
        YtrDropDownLabel_2       matlab.ui.control.Label
        XvalDropDown             matlab.ui.control.DropDown
        ValCheckBox              matlab.ui.control.CheckBox
        YtrDropDown              matlab.ui.control.DropDown
        YtrDropDownLabel         matlab.ui.control.Label
        XtrDropDown              matlab.ui.control.DropDown
        XtrDropDownLabel         matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        TestTab_2                matlab.ui.container.Tab
        DropDown                 matlab.ui.control.DropDown
        DropDownLabel            matlab.ui.control.Label
        LoadNetDropDown          matlab.ui.control.DropDown
        LoadNetDropDownLabel     matlab.ui.control.Label
        XteDropDown              matlab.ui.control.DropDown
        XteDropDownLabel         matlab.ui.control.Label
        YteDropDown              matlab.ui.control.DropDown
        YteDropDownLabel         matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        TestButton               matlab.ui.control.Button
        TestPanel                matlab.ui.container.Panel
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            workspaceVars = evalin('base', 'who');
            app.YtrDropDown.Items = workspaceVars;
            app.XtrDropDown.Items = workspaceVars;
            app.XteDropDown.Items = workspaceVars;
            app.YteDropDown.Items = workspaceVars;
            app.StopButton_2.Tag = '0';
            sizeWend = size(evalin('base', app.YtrDropDown.Value),2);
            val = ones(1,app.LEditField.Value-1)*3*sizeWend;
            app.wEditField.Value = ['[',num2str([val,sizeWend]),']'];
            app.XvalDropDown.Items = workspaceVars;
            app.YvalDropDown.Items = workspaceVars;
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            sizeWend = size(evalin('base', app.YtrDropDown.Value),2);
            val = ones(1,app.LEditField.Value-1)*3*sizeWend;
            app.wEditField.Value = ['[',num2str([val,sizeWend]),']'];
            app.wEditField.Enable = "on";
        end

        % Value changed function: YtrDropDown
        function YtrDropDownValueChanged(app, event)
            sizeWend = size(evalin('base', app.YtrDropDown.Value),2);
            val = ones(1,app.LEditField.Value-1)*3*sizeWend;
            app.wEditField.Value = ['[',num2str([val,sizeWend]),']'];
            app.wEditField.Enable = "on";
            XtrDropDownValueChanged(app)
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.TrainButton.Enable = "off";
            app.TrainButton.Text = 'Training...';
            app.TrainButton.Layout.Column = [3 5];
            app.XtrDropDown.Enable = "off";
            app.YtrDropDown.Enable = "off";
            app.LEditField.Enable = "off";
            app.wEditField.Enable = "off";
            app.deltaEditField.Enable = "off";
            app.alphaEditField.Enable = "off";
            app.epochEditField.Enable = "off";
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Xtrain = evalin('base', app.XtrDropDown.Value);
            Ytrain = evalin('base', app.YtrDropDown.Value);
            if 1
                Xval = evalin('base', app.XvalDropDown.Value);
                Yval = evalin('base', app.YvalDropDown.Value);
            end
            L = app.LEditField.Value;
            w = str2num(app.wEditField.Value);
            EPOCH = app.epochEditField.Value;

            delta = app.deltaEditField.Value;
            alpha = app.alphaEditField.Value;
            net = MSOFNN(Xtrain,Ytrain,L,w,delta,alpha);

            % TRAIN
            MSE = inf(EPOCH,1);
            if app.ValCheckBox.Value, MSEval = inf(EPOCH,1); end
            bestMSE = inf;
            for epoch = 1:EPOCH
                if str2double(app.StopButton_2.Tag), break, end

                [net, MSE(epoch)] = net.main(Xtrain, Ytrain, epoch);
                % if (epoch > 1) && (abs(MSE(epoch) - MSE(epoch-1)) < 1e-6), break, end
                
                if MSE(epoch) < bestMSE
                    bestEpoch = epoch;
                    bestMSE = MSE(epoch);
                    bestNet = net;
                end
                plot(app.UIAxes, 1:epoch, MSE(1:epoch)), hold(app.UIAxes,"on")
                scatter(app.UIAxes, bestEpoch, bestMSE, 'MarkerEdgeColor',[0 .5 .5],...
                                                        'MarkerFaceColor',[0 .7 .7],...
                                                        'LineWidth',1.5)
                if app.ValCheckBox.Value
                    [~,err] = net.test(Xval,Yval);
                    MSEval(epoch) = err.MSE;
                    plot(app.UIAxes, 1:epoch, MSEval(1:epoch), 'k')
                end
                drawnow
                hold(app.UIAxes,"off")
            end
            % save Best and Last net
            assignin('base', 'BestTrainedNet', bestNet);
            assignin('base', 'LastTrainedNet', net);
            app.LoadNetDropDown.Items = evalin('base', 'who');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.StopButton_2.Visible = "off";
            app.TrainButton.Text = 'Trained';
            app.TrainButton.Layout.Column = [3 6];
            app.TestButton.Visible = "on";
            app.XteDropDown.Visible = "on";
            app.YteDropDown.Visible = "on";
            app.TrainButton.BackgroundColor = [0.47,0.67,0.19];
            app.TrainButton.FontColor = [1 1 1];
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            app.StopButton_2.Tag = num2str(1);
        end

        % Value changed function: XtrDropDown
        function XtrDropDownValueChanged(app, event)
            app.Label.Text = "Data(" + size(evalin('base', app.XtrDropDown.Value),1)...
                + ")  Feature(" + size(evalin('base', app.XtrDropDown.Value),2) + ","+size(evalin('base', app.YtrDropDown.Value),2)+")";
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            Xtest = evalin('base', app.XteDropDown.Value);
            Ytest = evalin('base', app.YteDropDown.Value);
            net = evalin('base', app.LoadNetDropDown.Value);
            [yhat,err] = net.test(Xtest,Ytest);
            assignin('base', 'testedyhat', yhat);
            app.Label_2.Text = sprintf('MSE: %.3f \nRMSE: %.3f \nNDEI: %.3f',err.MSE,err.RMSE,err.NDEI);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TestPanel = uipanel(app.UIFigure);
            app.TestPanel.Title = 'Test Panel';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.YteDropDownLabel = uilabel(app.UIFigure);
            app.YteDropDownLabel.Text = 'Yte';
            app.YteDropDown = uidropdown(app.UIFigure);
            app.YteDropDown.Items = {'X_train', 'Y_train', 'X_test', 'Y_test'};
            app.YteDropDown.Value = 'Y_test';
            app.XteDropDownLabel = uilabel(app.UIFigure);
            app.XteDropDownLabel.Text = 'Xte';
            app.XteDropDown = uidropdown(app.UIFigure);
            app.XteDropDown.Items = {'X_train', 'Y_train', 'X_test', 'Y_test'};
            app.XteDropDown.Value = 'X_test';
            app.LoadNetDropDownLabel = uilabel(app.UIFigure);
            app.LoadNetDropDownLabel.Text = 'Load Net';
            app.LoadNetDropDown = uidropdown(app.UIFigure);
            app.LoadNetDropDown.Items = {'LastTrainedNet', 'BestTrainedNet'};
            app.LoadNetDropDown.Value = 'LastTrainedNet';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TrainTab_2 = uitab(app.TabGroup2);
            app.TrainTab_2.Title = 'Train';
            app.GridLayout3 = uigridlayout(app.TrainTab_2);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Learning Plot')
            xlabel(app.UIAxes, 'EPOCH')
            ylabel(app.UIAxes, 'MSE')
            zlabel(app.UIAxes, 'Z')
            app.XtrDropDownLabel = uilabel(app.GridLayout3);
            app.XtrDropDownLabel.Text = 'Xtr';
            app.XtrDropDown = uidropdown(app.GridLayout3);
            app.XtrDropDown.Items = {'X_train', 'Y_train', 'X_test', 'Y_test'};
            app.XtrDropDown.ValueChangedFcn = createCallbackFcn(app, @XtrDropDownValueChanged, true);
            app.XtrDropDown.Value = 'X_train';
            app.YtrDropDownLabel = uilabel(app.GridLayout3);
            app.YtrDropDownLabel.Text = 'Ytr';
            app.YtrDropDown = uidropdown(app.GridLayout3);
            app.YtrDropDown.Items = {'X_train', 'Y_train', 'X_test', 'Y_test'};
            app.YtrDropDown.ValueChangedFcn = createCallbackFcn(app, @YtrDropDownValueChanged, true);
            app.YtrDropDown.Value = 'Y_train';
            app.ValCheckBox = uicheckbox(app.GridLayout3);
            app.ValCheckBox.Text = 'Use validation data';
            app.XvalDropDown = uidropdown(app.GridLayout3);
            app.XvalDropDown.Items = {};
            app.XvalDropDown.Value = {};
            app.YtrDropDownLabel_2 = uilabel(app.GridLayout3);
            app.YtrDropDownLabel_2.Text = 'Xval';
            app.YtrDropDownLabel_3 = uilabel(app.GridLayout3);
            app.YtrDropDownLabel_3.Text = 'Yval';
            app.YvalDropDown = uidropdown(app.GridLayout3);
            app.YvalDropDown.Items = {};
            app.YvalDropDown.Value = {};
            app.Panel = uipanel(app.GridLayout3);
            app.oruseEditField = uieditfield(app.Panel, 'numeric');
            app.OruseLabel = uilabel(app.Panel);
            app.OruseLabel.Text = 'Or use';
            app.ofTraindataLabel = uilabel(app.Panel);
            app.ofTraindataLabel.Text = '% of Train data';
            app.ParametersPanel = uipanel(app.GridLayout3);
            app.ParametersPanel.Title = 'Parameters';
            app.LEditFieldLabel = uilabel(app.ParametersPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.LEditField.Limits = [1 Inf];
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Tooltip = {'number of layer'};
            app.LEditField.Value = 2;
            app.epochEditFieldLabel = uilabel(app.ParametersPanel);
            app.epochEditFieldLabel.Text = 'epoch';
            app.epochEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.epochEditField.Limits = [1 Inf];
            app.epochEditField.Tooltip = {'number of maximum epoch'};
            app.epochEditField.Value = 200;
            app.alphaEditFieldLabel = uilabel(app.ParametersPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.alphaEditField.Limits = [0 Inf];
            app.alphaEditField.Tooltip = {'learning rate'};
            app.alphaEditField.Value = 1;
            app.deltaEditFieldLabel = uilabel(app.ParametersPanel);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.deltaEditField.Limits = [0 Inf];
            app.deltaEditField.Tooltip = {'thereshold'};
            app.deltaEditField.Value = 0.0497870683678639;
            app.wEditFieldLabel = uilabel(app.ParametersPanel);
            app.wEditFieldLabel.Enable = 'off';
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.ParametersPanel, 'text');
            app.wEditField.Enable = 'off';
            app.AFEditFieldLabel = uilabel(app.ParametersPanel);
            app.AFEditFieldLabel.Text = 'AF';
            app.AFEditField = uieditfield(app.ParametersPanel, 'text');
            app.TrainButton = uibutton(app.GridLayout3, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.StopButton_2 = uibutton(app.GridLayout3, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.TestTab_2 = uitab(app.TabGroup2);
            app.TestTab_2.Title = 'Test';
            app.PoweredbyITHENOAv1Label = uilabel(app.UIFigure);
            app.PoweredbyITHENOAv1Label.Text = 'Powered by ITHENOA   v1';
            app.MSOFNNLabel = uilabel(app.UIFigure);
            app.MSOFNNLabel.Interpreter = 'tex';
            app.MSOFNNLabel.Text = 'MSOFNN+';
            app.DropDown2Label = uilabel(app.UIFigure);
            app.DropDown2Label.Text = 'Drop Down2';
            app.DropDown2 = uidropdown(app.UIFigure);
            app.SolverEditFieldLabel = uilabel(app.UIFigure);
            app.SolverEditFieldLabel.Text = 'Solver';
            app.SolverEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSOFNN3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end