classdef SOMFNN_Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout9               matlab.ui.container.GridLayout
        PoweredbyITHENOAv2Label   matlab.ui.control.Label
        SOMFNNLabel               matlab.ui.control.Label
        TabGroup2                 matlab.ui.container.TabGroup
        TrainTab_2                matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        TrainingSettingsPanel     matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        ValFreqEditField          matlab.ui.control.NumericEditField
        ValFreqEditFieldLabel     matlab.ui.control.Label
        b1EditField               matlab.ui.control.NumericEditField
        b1EditFieldLabel          matlab.ui.control.Label
        b2EditField               matlab.ui.control.NumericEditField
        b2EditFieldLabel          matlab.ui.control.Label
        m0EditField               matlab.ui.control.NumericEditField
        m0EditFieldLabel          matlab.ui.control.Label
        v0EditField               matlab.ui.control.NumericEditField
        v0EditFieldLabel          matlab.ui.control.Label
        MBEditField               matlab.ui.control.NumericEditField
        MBEditFieldLabel          matlab.ui.control.Label
        PatienceEditField         matlab.ui.control.NumericEditField
        PatienceEditFieldLabel    matlab.ui.control.Label
        RegTemEditField           matlab.ui.control.NumericEditField
        lambdaLabel               matlab.ui.control.Label
        LRScheduleEditField       matlab.ui.control.EditField
        LRScheduleEditFieldLabel  matlab.ui.control.Label
        SolverDropDown            matlab.ui.control.DropDown
        SolverDropDownLabel       matlab.ui.control.Label
        deltaEditField            matlab.ui.control.NumericEditField
        deltaEditFieldLabel       matlab.ui.control.Label
        NodesEditField            matlab.ui.control.EditField
        NodesEditFieldLabel       matlab.ui.control.Label
        AFEditField               matlab.ui.control.EditField
        AFEditFieldLabel          matlab.ui.control.Label
        LEditField                matlab.ui.control.NumericEditField
        LEditFieldLabel           matlab.ui.control.Label
        epochEditField            matlab.ui.control.NumericEditField
        epochEditFieldLabel       matlab.ui.control.Label
        alphaEditField            matlab.ui.control.NumericEditField
        alphaEditFieldLabel       matlab.ui.control.Label
        Panel_2                   matlab.ui.container.Panel
        GridLayout8               matlab.ui.container.GridLayout
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        Panel                     matlab.ui.container.Panel
        infVal3                   matlab.ui.control.Label
        infVal2                   matlab.ui.control.NumericEditField
        infVal1                   matlab.ui.control.Label
        StopButton                matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        TrainButton               matlab.ui.control.Button
        YvalDropDown              matlab.ui.control.DropDown
        YvalLabel                 matlab.ui.control.Label
        XvalLabel                 matlab.ui.control.Label
        XvalDropDown              matlab.ui.control.DropDown
        ValCheckBox               matlab.ui.control.CheckBox
        YtrDropDown               matlab.ui.control.DropDown
        YtrDropDownLabel          matlab.ui.control.Label
        XtrDropDown               matlab.ui.control.DropDown
        XtrDropDownLabel          matlab.ui.control.Label
        RulePruningTab            matlab.ui.container.Tab
        GridLayout5               matlab.ui.container.GridLayout
        ComingSoonLabel           matlab.ui.control.Label
        PruneRulesButton          matlab.ui.control.Button
        LoadNet_RulePrun          matlab.ui.control.DropDown
        LoadNetDropDown_2Label    matlab.ui.control.Label
        TestTab_2                 matlab.ui.container.Tab
        GridLayout6               matlab.ui.container.GridLayout
        Button_2                  matlab.ui.control.Button
        TabGroup3                 matlab.ui.container.TabGroup
        Test1Tab                  matlab.ui.container.Tab
        GridLayout7               matlab.ui.container.GridLayout
        Label_3                   matlab.ui.control.Label
        TestButton                matlab.ui.control.Button
        YteDropDown               matlab.ui.control.DropDown
        YteDropDownLabel          matlab.ui.control.Label
        XteDropDown               matlab.ui.control.DropDown
        XteDropDownLabel          matlab.ui.control.Label
        LoadNetDropDown           matlab.ui.control.DropDown
        LoadNetDropDownLabel      matlab.ui.control.Label
        UIAxesTest1               matlab.ui.control.UIAxes
        Label_2                   matlab.ui.control.Label
    end


    properties (Access = private)
        parpush_flag = 0 % Description
        nTestTab = 0 % Description
        testTab
        testGridLayout % Description
        testUIAxis % Description
        testLoadNetDropDownLabel % Description
        testLoadNetDropDown % Description
        testXteDropDownLabel % Description
        testXteDropDown % Description
        testYteDropDownLabel % Description
        testYteDropDown % Description
        testTestButton % Description
        testLabel_3 % Description
    end

    methods (Access = private)

        function [trained_net,valData] = Train(app,net,opts)
            arguments
                app
                net
                % or
                opts.Xval = []
                opts.Yval = []
                % or
                opts.validationSplitPercent = 0
                % optional
                opts.valPerEpochFrequency = 1 % test val every 1 epochs
                opts.ApplyRuleRemover {logical} = 0
                opts.LiveOutPlot = "none" % [defult="none", dataTest, "useTrainData", "useValData"]
                opts.LiveOutPlotFrequency = 5
                opts.EarlyStoppingPatience {mustBeNumeric} = inf
            end
            % Error check
            if ~opts.validationSplitPercent && opts.ApplyRuleRemover
                warning("Validation data not exist, Rule-Remover will work with training data, which it may havent good performance.")
            end
            if opts.validationSplitPercent && (exist("opts.Xval","var") || exist("opts.Yval","var"))
                if (exist("opts.Xval","var") && exist("opts.Yval","var"))
                    warning("Validation data entered, so set 'valPerEpochFrequency' to zero.");
                else
                    error("Enter both of Xval and Yval")
                end
            end

            net = net.StringValidate();
            % if net.Plot, fPlot = figure(Name="Process",NumberTitle="off"); end
            if opts.validationSplitPercent || ~isempty(opts.Xval)
                net.validation_flag = 1;
            end
            valData = [];
            if net.validation_flag % Validation
                if ~isempty(opts.Xval)
                    Xval = opts.Xval;
                    Yval = opts.Yval;
                    % n_val = size(Xval,1);
                else
                    idx = randperm(size(net.Xtrain,1));
                    n_val = round(size(net.Xtrain,1) * opts.validationSplitPercent);
                    Xval = net.Xtrain(idx(1:n_val),:);
                    Yval = net.Ytrain(idx(1:n_val),:);
                    net.Xtrain = net.Xtrain(idx(n_val+1:end),:);
                    net.Ytrain = net.Ytrain(idx(n_val+1:end),:);
                end
                bestValLoss = inf;
                valEpochs = opts.valPerEpochFrequency:opts.valPerEpochFrequency:net.MaxEpoch;
                METRIC_val = zeros(1,numel(valEpochs));
                LOSS_val = zeros(1,numel(valEpochs));
                valCount = 0;
                valStall = 0;
            end
            net.nTrData = size(net.Xtrain,1);

            % normalize data if needed
            [Xtr,Ytr] = net.NormalizeData(net.Xtrain,net.Ytrain);

            % % LiveOutPlot
            % if ~strcmp(opts.LiveOutPlot,"none")
            %     if strcmp(opts.LiveOutPlot,"useTrainData")
            %         LiveOutPlotX =  Xtr;
            %         LiveOutPlotY = Ytr;
            %     elseif strcmp(opts.LiveOutPlot,"useValData")
            %         if ~net.validation_flag, error("use validation data"),end
            %         LiveOutPlotX =  Xval;
            %         LiveOutPlotY = Yval;
            %     elseif isnumeric(opts.LiveOutPlot)
            %         LiveOutPlotX =  opts.LiveOutPlot(:,1:end-1);
            %         LiveOutPlotY = opts.LiveOutPlot(:,end);
            %         if net.classification_flag == 2 && min(unique(LiveOutPlotY)) == 0
            %             LiveOutPlotY = LiveOutPlotY + 1;
            %         end
            %     else, error("Invalid 'LiveOutPlot' option name.")
            %     end
            %     opts.LiveOutPlot = 1;
            %     fLive = figure(Name="Live Output with user data entered",NumberTitle="off");
            % else
            %     opts.LiveOutPlot = 0;
            % end

            it = 0; % iteration total
            METRIC_tr = zeros(1,net.MaxEpoch);
            LOSS_tr = zeros(1,net.MaxEpoch);
            net.dataSeenCounter = 0;

            %%%%%%%% epoch %%%%%%%%
            for epoch = 1:net.MaxEpoch
                if str2double(app.StopButton.Tag), break, end

                % Schedule Learning Rate
                if net.LRScheduler_flag && ismember(epoch,net.LearningRateSchedule(1:2:end))
                    net.LearningRate = net.LearningRateSchedule(find(epoch == net.LearningRateSchedule(1:2:end))*2);
                end

                yhat = zeros(net.nTrData,net.n_nodes(end));
                shuffle_idx = randperm(size(Xtr,1));
                %%%%%%%%%%% iteration %%%%%%%%%%%
                for it_epoch = 1 : ceil(size(Xtr,1)/net.MiniBatchSize)
                    it = it + 1;
                    MB_idx = shuffle_idx( (it_epoch-1)*net.MiniBatchSize+1 : min(it_epoch*net.MiniBatchSize,size(Xtr,1)) );

                    x = Xtr(MB_idx,:)';
                    if ~strcmp(net.BatchNormType,"none")
                        x = normalize(x,1,net.BatchNormType); % dim=2; normalize each feature
                    end
                    y = Ytr(MB_idx,:)';

                    % Forward >> Backward
                    [net,yhat_MB] = net.Main(x,y,it);

                    % save pars
                    yhat(MB_idx,:) = yhat_MB'; % error per it
                    net.dataSeenCounter = net.dataSeenCounter + numel(MB_idx);
                end %%%%%%%%%%% END iteration %%%%%%%%%%%

                %%%%%%%%%%% epoch - results %%%%%%%%%%%

                % un normalize data (if needed)
                yhat = net.UnNormalizeOutput(yhat);

                % calculate loss
                LOSS_tr(epoch) = net.GetLoss(yhat,net.Ytrain);

                % class label detection (if needed)
                if net.classification_flag
                    yhat = net.GetClassLabel(yhat);
                end

                % calculate metrices
                METRIC_tr(epoch) = net.GetMetric(yhat,net.Ytrain); % MSE/ACC for regression/classification

                % % verbose
                % if net.Verbose
                %     if ~rem(epoch,net.Verbose)
                %         if net.regression_flag
                %             fprintf("[Epoch:%d] [MSE:%.4f] [RMSE:%.4f] \n", epoch, METRIC_tr(epoch), sqrt(METRIC_tr(epoch)))
                %         else
                %             fprintf("[Epoch:%d] [ACC:%.3f] [Loss:%.4f] \n", epoch, METRIC_tr(epoch), LOSS_tr(epoch))
                %         end
                %     end
                % end

                % % LiveOutPlot
                % if opts.LiveOutPlot && ~rem(epoch,opts.LiveOutPlotFrequency)
                %     [yhLive,~] = net.Test(LiveOutPlotX);
                %     figure(fLive),hold on
                %     if net.regression_flag
                %         plot(1:numel(LiveOutPlotY),LiveOutPlotY)
                %         plot(1:numel(LiveOutPlotY),yhLive)
                %         legend("Refrence","Predicted")
                %     else
                %         plotconfusion(categorical(LiveOutPlotY),categorical(yhLive))
                %     end
                %     drawnow,hold off
                % end

                % validation
                if net.validation_flag && ismember(epoch,valEpochs)
                    valCount = valCount + 1;
                    [~,metric_val] = net.Test(Xval,Yval);
                    METRIC_val(valCount) = metric_val.MSEorACC; % (reg=MSE),(clas=ACC)
                    LOSS_val(valCount) = metric_val.LOSS;
                    if valCount>1
                        if (LOSS_val(valCount) < bestValLoss)
                            netBest = net;
                            bestValLoss = LOSS_val(valCount);
                            valStall = 0;
                            % o.RegularizationTerm = o.RegularizationTerm * 1.01;
                        else
                            valStall = valStall + 1;
                            if valStall > opts.EarlyStoppingPatience, break, end
                            % if LOSS_tr(epoch) < min(LOSS_tr(1:epoch-1))
                            % o.RegularizationTerm = max(o.RegularizationTerm * 0.9,0.001);
                            % if o.RegularizationTerm < 0.0001
                            %     o.RegularizationTerm = 0.01;
                            % end
                            % o.RegularizationTerm
                            % end

                        end
                    end
                    % if valCount > 40 && LOSS_val(valCount) > LOSS_val(valCount-40)
                    %     o.RegularizationTerm = 0.01;
                    %     o.RegularizationTerm
                    % end
                end

                % PLOT
                if net.classification_flag
                    % yyaxis(app.UIAxes, 'left');
                    % top
                    plot(app.UIAxes, 1:epoch, METRIC_tr(1:epoch)*100, "LineWidth",1.5, DisplayName="Train Accuracy")
                    legend(app.UIAxes, 'show', 'Location','best')
                    if net.validation_flag
                    hold(app.UIAxes, "on")
                    line(app.UIAxes, valEpochs(1:valCount), METRIC_val(1:valCount)*100, 'LineStyle', '--', 'Color', 'k', 'Marker', 'o', ...
                        'MarkerFaceColor', 'k','DisplayName', "Validation Accuracy", ...
                        'MarkerSize',5);
                    ylabel(app.UIAxes, 'Accuracy');
                    ylim(app.UIAxes, [0,105])
                    hold(app.UIAxes, "off")
                    end
                    % down
                    plot(app.UIAxes_2, 1:epoch, LOSS_tr(1:epoch), "LineWidth",1.5, DisplayName="Train Loss")
                    legend(app.UIAxes_2, 'show', 'Location','best')
                    if net.validation_flag
                    hold(app.UIAxes_2, "on")
                    line(app.UIAxes_2, valEpochs(1:valCount), LOSS_val(1:valCount), 'LineStyle', '--', 'Color', 'k', 'Marker', 'o', ...
                        'MarkerFaceColor', 'k','DisplayName', "Validation Loss", ...
                        'MarkerSize',5);
                    ylabel(app.UIAxes_2, 'Log Loss');
                    hold(app.UIAxes_2, "off")
                    end
                elseif net.regression_flag
                    % top
                    plot(app.UIAxes, 1:epoch, sqrt(METRIC_tr(1:epoch)), "LineWidth",1.5, DisplayName="Train RMSE")
                    legend(app.UIAxes, 'show', 'Location','best')
                    if net.validation_flag
                    hold(app.UIAxes, "on")
                    line(app.UIAxes, valEpochs(1:valCount), sqrt(METRIC_val(1:valCount)), 'LineStyle', '--', 'Color', 'k', 'Marker', 'o', ...
                        'MarkerFaceColor', 'k','DisplayName', "Validation RMSE", ...
                        'MarkerSize',5);
                    ylabel(app.UIAxes, 'RMSE');
                    hold(app.UIAxes, "off")
                    end
                    % down
                    plot(app.UIAxes_2, 1:epoch, METRIC_tr(1:epoch), "LineWidth",1.5, DisplayName="Train MSE")
                    legend(app.UIAxes_2, 'show', 'Location','best')
                    if net.validation_flag
                    hold(app.UIAxes_2, "on")
                    line(app.UIAxes_2, valEpochs(1:valCount), METRIC_val(1:valCount), 'LineStyle', '--', 'Color', 'k', 'Marker', 'o', ...
                        'MarkerFaceColor', 'k','DisplayName', "Validation MSE", ...
                        'MarkerSize',5);
                    ylabel(app.UIAxes_2, 'MSE');
                    hold(app.UIAxes_2, "off")
                    end
                end
                drawnow

                % plot(app.UIAxes, 1:epoch, MSE(1:epoch)), hold(app.UIAxes,"on")
                % scatter(app.UIAxes, bestEpoch, bestMSE, 'MarkerEdgeColor',[0 .5 .5],...
                %     'MarkerFaceColor',[0 .7 .7],...
                %     'LineWidth',1.5)
                % if app.ValCheckBox.Value
                %     [~,err] = net.test(Xval,Yval);
                %     MSEval(epoch) = err.MSE;
                %     plot(app.UIAxes, 1:epoch, MSEval(1:epoch), 'k')
                % end


                % % Plot (old)
                % if (net.Plot) && (epoch > 1)
                %     try figure(fPlot),catch,end
                %     if net.classification_flag
                %         if net.validation_flag
                %             net.GetPlot(METRIC_tr(1:epoch)*100, LOSS_tr(1:epoch), METRIC_val(1:valCount)*100, LOSS_val(1:valCount), valEpochs(1:valCount), ["Acuracy","Loss"])
                %         else
                %             net.GetPlot(METRIC_tr(1:epoch)*100, LOSS_tr(1:epoch), [], [], [], ["Acuracy","Loss"])
                %         end
                %     elseif net.regression_flag
                %         if net.validation_flag
                %             net.GetPlot(METRIC_tr(1:epoch), sqrt(METRIC_tr(1:epoch)), METRIC_val(1:valCount), sqrt(METRIC_val(1:valCount)), valEpochs(1:valCount), ["MSE","RMSE"])
                %         else
                %             net.GetPlot(METRIC_tr(1:epoch), sqrt(METRIC_tr(1:epoch)), [], [], [], ["MSE","RMSE"])
                %         end
                %     end
                % end

                net.TrainedEpoch = epoch;
                % Eliminate Condition
                if (epoch>5) && prod(METRIC_tr(epoch-5:end) - METRIC_tr(epoch)), break, end

            end %%%%%%%%%%% END EPOCH %%%%%%%%%%%

            % % Apply Rule Remover (if needed)
            % if opts.ApplyRuleRemover
            %     if net.validation_flag && epoch>1
            %         net = net.RuleRemover(Xval,0.5,0.5);
            %         netBest = netBest.RuleRemover(Xval,0.5,0.5);
            %     else
            %         net = net.RuleRemover(net.Xtrain,0.5,0.5);
            %     end
            % end

            % save network
            if net.validation_flag && epoch>1
                trained_net.last = net;
                trained_net.bestVal = netBest;
                valData.x = Xval;
                valData.y = Yval;
            else
                trained_net = net;
            end

            % return results
            % [bestMSE,bestIdx] = min(METRIC_tr);
            % meanMSE = mean(METRIC_tr);
            % o.MSE_report.Mean = meanMSE;
            % o.MSE_report.Best = bestMSE;
            % o.MSE_report.Last = METRIC_tr(end);
            %
            % METRIC_tr = ["Last";"Best";"Mean"];
            % Value = [METRIC_tr(end); bestMSE; meanMSE];
            % Epoch = [epoch; bestIdx; nan];
            % table(METRIC_tr,Value,Epoch)
            % o.MSE_report = sprintf("[Mean:%.3f, Best:%.3f]",mean(MSE_ep),min(MSE_ep));
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            workspaceVars = evalin('base', 'who');
            app.YtrDropDown.Items = workspaceVars;
            app.XtrDropDown.Items = workspaceVars;
            app.XteDropDown.Items = workspaceVars;
            app.YteDropDown.Items = workspaceVars;
            app.LoadNetDropDown.Items = workspaceVars;
            app.StopButton.Tag = '0';
            sizeWend = size(evalin('base', app.YtrDropDown.Value),2);
            val = ones(1,app.LEditField.Value-1)*3*sizeWend;
            nFeatures = size(evalin('base', app.XtrDropDown.Value),2);
            app.NodesEditField.Value = ['[',num2str([nFeatures,val,sizeWend]),']'];
            app.XvalDropDown.Items = workspaceVars;
            app.YvalDropDown.Items = workspaceVars;
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            sizeWend = size(evalin('base', app.YtrDropDown.Value),2);
            val = ones(1,app.LEditField.Value-1)*3*sizeWend;
            nFeatures = size(evalin('base', app.XtrDropDown.Value),2);
            app.NodesEditField.Value = ['[',num2str([nFeatures,val,sizeWend]),']'];
            app.NodesEditField.Enable = "on";
        end

        % Value changed function: YtrDropDown
        function YtrDropDownValueChanged(app, event)
            sizeWend = size(evalin('base', app.YtrDropDown.Value),2);
            val = ones(1,app.LEditField.Value-1)*3*sizeWend;
            nFeatures = size(evalin('base', app.XtrDropDown.Value),2);
            app.NodesEditField.Value = ['[',num2str([nFeatures,val,sizeWend]),']'];
            app.NodesEditField.Enable = "on";
            XtrDropDownValueChanged(app)
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.TrainButton.Enable = "off";
            app.TrainButton.Text = 'Training...';
            app.TrainButton.Layout.Column = [3 5];
            app.XtrDropDown.Enable = "off";
            app.YtrDropDown.Enable = "off";
            app.LEditField.Enable = "off";
            app.NodesEditField.Enable = "off";
            app.deltaEditField.Enable = "off";
            app.alphaEditField.Enable = "off";
            app.epochEditField.Enable = "off";
            app.StopButton.Visible = "on";
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Xtrain = evalin('base', app.XtrDropDown.Value);
            Ytrain = evalin('base', app.YtrDropDown.Value);
            Xval = [];
            Yval = [];
            if app.ValCheckBox.Value && ~app.infVal2.Value
                Xval = evalin('base', app.XvalDropDown.Value);
                Yval = evalin('base', app.YvalDropDown.Value);
            end
            L = app.LEditField.Value;
            w = str2num(app.NodesEditField.Value);
            EPOCH = app.epochEditField.Value;

            delta = app.deltaEditField.Value;
            alpha = app.alphaEditField.Value;
            % network options
            net = appCode(Xtrain,Ytrain,L,...
                "n_hiddenNodes",w(2:end-1),... [vector,"auto"]
                "ActivationFunction", eval(app.AFEditField.Value),... [rel,leaky,sig,tan,lin,elu]
                "KernelsName",["RBF"],... ["RBF","COS","Linear","Polynomial","Laplacian","Chi2"]
                "KernelsWeight",[1], ... sum = 1
                "DensityThreshold", delta,...
                "MaxEpoch", EPOCH,...
                "LearningRateSchedule","none",... ["none",[epoch,LR,...,epoch,LR]]
                "BatchNormType", "none",... temp
                "LearningRate", alpha,...
                "LearningRateSchedule",eval(app.LRScheduleEditField.Value),... ["none",[epoch,LR,...,epoch,LR]]
                "SolverName", "mini",... [mini,sgd,adam]
                "WeightInitializationType", "mean",... [mean,rand,xavier]
                "DataNormalize" , "none",... ["none","X","Y","XY"]
                "NormalizeDimension","eachFeature",... ["eachFeature","dataFeatures"]
                "MiniBatchSize", app.MBEditField.Value,...
                "adampar_beta1", app.b1EditField.Value,...
                "adampar_beta2", app.b2EditField.Value,...
                "adampar_epsilon", 1e-8,...
                "adampar_m0", app.m0EditField.Value,...
                "adampar_v0", app.v0EditField.Value,...
                "Plot", 0,... [0,1]
                "Verbose", 0, ... [0:MaxEpoch)
                "RegularizationTerm",app.RegTemEditField.Value, ...[0:inf)
                "updatePrototype",0 ...[0,1]
                );
            app.NodesEditField.Value = num2str(net.n_nodes);

            % TRAIN
            [trained_net,valData] = app.Train(net, ...
                "Xval",Xval,...
                "Yval",Yval,...
                "validationSplitPercent",app.infVal2.Value/100,... [0:1)
                "valPerEpochFrequency",1,... [0:MaxEpoch)
                "ApplyRuleRemover",0, ... [0,1]
                "LiveOutPlot","none", ... ["none",dataTest,"useTrainData","useValData"]
                "LiveOutPlotFrequency",5, ...
                "EarlyStoppingPatience",app.PatienceEditField.Value ... [0:MaxEpoch)
                );

            % save Best and Last net
            if app.ValCheckBox.Value
                assignin('base', 'BestValTrainedNet', trained_net.bestVal);
                assignin('base', 'LastTrainedNet', trained_net.last);
            else
                assignin('base', 'TrainedNet', trained_net);
            end
            app.LoadNetDropDown.Items = evalin('base', 'who');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.StopButton.Visible = "off";
            app.TrainButton.Text = 'Trained';
            app.TrainButton.Layout.Column = [3 6];
            app.TestButton.Visible = "on";
            app.XteDropDown.Visible = "on";
            app.YteDropDown.Visible = "on";
            app.TrainButton.BackgroundColor = [0.47,0.67,0.19];
            app.TrainButton.FontColor = [1 1 1];
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.Tag = num2str(1);
        end

        % Value changed function: XtrDropDown
        function XtrDropDownValueChanged(app, event)
            app.Label.Text = "Data(" + size(evalin('base', app.XtrDropDown.Value),1)...
                + ")  Feature(" + size(evalin('base', app.XtrDropDown.Value),2) + ","+size(evalin('base', app.YtrDropDown.Value),2)+")";
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            Xtest = evalin('base', app.XteDropDown.Value);
            Ytest = evalin('base', app.YteDropDown.Value);
            net = evalin('base', app.LoadNetDropDown.Value);
            [yhat,metrics] = net.Test(Xtest,Ytest, "Plot", 0, "MetricReport", "all");
            assignin('base', "yhat_Test_1", yhat);
            if net.classification_flag
                app.Label_3.Text = sprintf('ACC: %.4f\nBACC: %.4f\nPREC: %.4f\nRECALL: %.4f\nF1SCORE: %.4f\nKAPPA: %.4f\nMCC: %.4f\nLOSS: %.4f', ...
                    metrics.ACC, metrics.BACC, (metrics.PREC), metrics.RECALL, (metrics.F1SCORE), metrics.KAPPA, metrics.MCC, metrics.LOSS);
            else
                app.Label_3.Text = sprintf('MSE: %.4f\nRMSE: %.4f\nNDEI: %.4f\nNDEI2: %.4f\nMAE: %.4f\nR2: %.4f\nMAPE: %.4f\nExplained Variance: %.4f\nMSLE: %.4f\nMBD: %.4f\nNRMSE: %.4f\nMPE: %.4f\nMAPE: %.4f', ...
                    metrics.MSE, metrics.RMSE, metrics.NDEI, metrics.NDEI2, metrics.MAE, metrics.R2, metrics.MAPE, metrics.explained_variance, metrics.explainedVariance, metrics.msle, metrics.mbd, metrics.nrmse, metrics.mpe, metrics.mape);
            end
            app.UIAxesTest1.Visible = "on";
            confMatrix = confusionmat(Ytest,yhat);
            imagesc(app.UIAxes, confMatrix);
            colormap(app.UIAxes, 'jet');
            colorbar(app.UIAxes);
            xlabel(app.UIAxes, 'Predicted Class');
            ylabel(app.UIAxes, 'Actual Class');
            title(app.UIAxes, 'Confusion Matrix');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.parpush_flag = ~app.parpush_flag;
            if app.parpush_flag
                app.Button.Icon = './app/triR.png';
                app.TrainingSettingsPanel.Visible = "on";
            else
                app.Button.Icon = './app/triL.png';
                app.TrainingSettingsPanel.Visible = "off";
            end
        end

        % Value changed function: SolverDropDown
        function SolverDropDownValueChanged(app, event)
            value = app.SolverDropDown.Value;
            if strcmp(value,'SGD')
                app.b1EditField.Enable = "off";
                app.b2EditField.Enable = "off";
                app.m0EditField.Enable = "off";
                app.v0EditField.Enable = "off";
                app.MBEditField.Enable = "off";
            elseif strcmp(value,'MiniBatchGD')
                app.b1EditField.Enable = "off";
                app.b2EditField.Enable = "off";
                app.m0EditField.Enable = "off";
                app.v0EditField.Enable = "off";
                app.MBEditField.Enable = "on";
            elseif strcmp(value,'ADAM')
                app.b1EditField.Enable = "on";
                app.b2EditField.Enable = "on";
                app.m0EditField.Enable = "on";
                app.v0EditField.Enable = "on";
                app.MBEditField.Enable = "on";
            end
        end

        % Value changed function: ValCheckBox
        function ValCheckBoxValueChanged(app, event)
            if app.ValCheckBox.Value
                flag = "on";
            else
                flag = "off";
            end
            app.ValFreqEditField.Enable = flag;
            app.infVal1.Enable = flag;
            app.infVal2.Enable = flag;
            app.infVal3.Enable = flag;
            app.XvalDropDown.Enable = flag;
            app.YvalDropDown.Enable = flag;
            app.YvalLabel.Enable = flag;
            app.XvalLabel.Enable = flag;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.nTestTab = app.nTestTab + 1;
            % addprop(app, "test_tab_" + app.nTestTab);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Create Test Tab
            app.testTab{app.nTestTab} = uitab(app.TabGroup3);
            app.testTab{app.nTestTab}.Title = "Test " + num2str(app.nTestTab+1);
            app.testTab{app.nTestTab}.ButtonDownFcn = createCallbackFcn(app, @Tab_pushed, true);

            % Create GridLayout7
            app.testGridLayout{app.nTestTab} = uigridlayout(app.testTab{app.nTestTab});
            app.testGridLayout{app.nTestTab}.ColumnWidth = {'1x', 30, 30, 30};
            app.testGridLayout{app.nTestTab}.RowHeight = {20, 25, 25, 25, 50, '1x', 200};

            % Create UIAxesTest1
            app.testUIAxis{app.nTestTab} = uiaxes(app.testGridLayout{app.nTestTab});
            title(app.testUIAxis{app.nTestTab}, 'Test Resault')
            xlabel(app.testUIAxis{app.nTestTab}, 'X')
            ylabel(app.testUIAxis{app.nTestTab}, 'Y')
            zlabel(app.testUIAxis{app.nTestTab}, 'Z')
            app.testUIAxis{app.nTestTab}.Color = [0.9412 0.9412 0.9412];
            app.testUIAxis{app.nTestTab}.Layout.Row = [1 7];
            app.testUIAxis{app.nTestTab}.Layout.Column = 1;

            % Create LoadNetDropDownLabel
            app.testLoadNetDropDownLabel{app.nTestTab} = uilabel(app.testGridLayout{app.nTestTab});
            app.testLoadNetDropDownLabel{app.nTestTab}.HorizontalAlignment = 'center';
            app.testLoadNetDropDownLabel{app.nTestTab}.VerticalAlignment = 'bottom';
            app.testLoadNetDropDownLabel{app.nTestTab}.WordWrap = 'on';
            app.testLoadNetDropDownLabel{app.nTestTab}.Layout.Row = 1;
            app.testLoadNetDropDownLabel{app.nTestTab}.Layout.Column = [2 4];
            app.testLoadNetDropDownLabel{app.nTestTab}.Text = 'Load Net';

            % Create LoadNetDropDown
            app.testLoadNetDropDown{app.nTestTab} = uidropdown(app.testGridLayout{app.nTestTab});
            app.testLoadNetDropDown{app.nTestTab}.Items = {'LastTrainedNet', 'BestTrainedNet'};
            app.testLoadNetDropDown{app.nTestTab}.Layout.Row = 2;
            app.testLoadNetDropDown{app.nTestTab}.Layout.Column = [2 4];
            app.testLoadNetDropDown{app.nTestTab}.Value = 'LastTrainedNet';

            % Create XteDropDownLabel
            app.testXteDropDownLabel{app.nTestTab} = uilabel(app.testGridLayout{app.nTestTab});
            app.testXteDropDownLabel{app.nTestTab}.HorizontalAlignment = 'center';
            app.testXteDropDownLabel{app.nTestTab}.Layout.Row = 3;
            app.testXteDropDownLabel{app.nTestTab}.Layout.Column = 2;
            app.testXteDropDownLabel{app.nTestTab}.Text = 'Xte';

            % Create XteDropDown
            app.testXteDropDown{app.nTestTab} = uidropdown(app.testGridLayout{app.nTestTab});
            app.testXteDropDown{app.nTestTab}.Items = {'X_train', 'Y_train', 'X_test', 'Y_test'};
            app.testXteDropDown{app.nTestTab}.Layout.Row = 3;
            app.testXteDropDown{app.nTestTab}.Layout.Column = [3 4];
            app.testXteDropDown{app.nTestTab}.Value = 'X_test';

            % Create YteDropDownLabel
            app.testYteDropDownLabel{app.nTestTab} = uilabel(app.testGridLayout{app.nTestTab});
            app.testYteDropDownLabel{app.nTestTab}.HorizontalAlignment = 'center';
            app.testYteDropDownLabel{app.nTestTab}.Layout.Row = 4;
            app.testYteDropDownLabel{app.nTestTab}.Layout.Column = 2;
            app.testYteDropDownLabel{app.nTestTab}.Text = 'Yte';

            % Create YteDropDown
            app.testYteDropDown{app.nTestTab} = uidropdown(app.testGridLayout{app.nTestTab});
            app.testYteDropDown{app.nTestTab}.Items = {'X_train', 'Y_train', 'X_test', 'Y_test'};
            app.testYteDropDown{app.nTestTab}.Layout.Row = 4;
            app.testYteDropDown{app.nTestTab}.Layout.Column = [3 4];
            app.testYteDropDown{app.nTestTab}.Value = 'Y_test';

            % Create TestButton
            app.testTestButton{app.nTestTab} = uibutton(app.testGridLayout{app.nTestTab}, 'push');
            app.testTestButton{app.nTestTab}.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.testTestButton{app.nTestTab}.Layout.Row = 5;
            app.testTestButton{app.nTestTab}.Layout.Column = [2 4];
            app.testTestButton{app.nTestTab}.Text = 'Test';

            % Create Label_3
            app.testLabel_3{app.nTestTab} = uilabel(app.testGridLayout{app.nTestTab});
            app.testLabel_3{app.nTestTab}.HorizontalAlignment = 'center';
            app.testLabel_3{app.nTestTab}.Layout.Row = 7;
            app.testLabel_3{app.nTestTab}.Layout.Column = [2 4];

            workspaceVars = evalin('base', 'who');
            app.testXteDropDown{app.nTestTab}.Items = workspaceVars;
            app.testYteDropDown{app.nTestTab}.Items = workspaceVars;
            app.testLoadNetDropDown{app.nTestTab}.Items = workspaceVars;
   
            % function Tab_pushed(app,~)
            %     app.CurrentTab = 
            % end

            function Test_pushed(app,~)
                Xtest = evalin('base', app.testXteDropDown{app.nTestTab}.Value);
                Ytest = evalin('base', app.testYteDropDown{app.nTestTab}.Value);
                net = evalin('base', app.testLoadNetDropDown{app.nTestTab}.Value);
                [yhat,metrics] = net.Test(Xtest,Ytest, "Plot", 0, "MetricReport", "all");
                assignin('base', "yhat_Test_" + app.nTestTab + 1, yhat);
                if net.classification_flag
                    app.Label_3.Text = sprintf('ACC: %.4f\nBACC: %.4f\nPREC: %.4f\nRECALL: %.4f\nF1SCORE: %.4f\nKAPPA: %.4f\nMCC: %.4f\nLOSS: %.4f', ...
                        metrics.ACC, metrics.BACC, (metrics.PREC), metrics.RECALL, (metrics.F1SCORE), metrics.KAPPA, metrics.MCC, metrics.LOSS);
                else
                    app.Label_3.Text = sprintf('MSE: %.4f\nRMSE: %.4f\nNDEI: %.4f\nNDEI2: %.4f\nMAE: %.4f\nR2: %.4f\nMAPE: %.4f\nExplained Variance: %.4f\nMSLE: %.4f\nMBD: %.4f\nNRMSE: %.4f\nMPE: %.4f\nMAPE: %.4f', ...
                        metrics.MSE, metrics.RMSE, metrics.NDEI, metrics.NDEI2, metrics.MAE, metrics.R2, metrics.MAPE, metrics.explained_variance, metrics.explainedVariance, metrics.msle, metrics.mbd, metrics.nrmse, metrics.mpe, metrics.mape);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.GridLayout9 = uigridlayout(app.UIFigure);
            app.TabGroup2 = uitabgroup(app.GridLayout9);
            app.TrainTab_2 = uitab(app.TabGroup2);
            app.TrainTab_2.Title = 'Train';
            app.GridLayout3 = uigridlayout(app.TrainTab_2);
            app.XtrDropDownLabel = uilabel(app.GridLayout3);
            app.XtrDropDownLabel.Text = 'Xtr';
            app.XtrDropDown = uidropdown(app.GridLayout3);
            app.XtrDropDown.Items = {'X_train', 'Y_train', 'X_test', 'Y_test'};
            app.XtrDropDown.ValueChangedFcn = createCallbackFcn(app, @XtrDropDownValueChanged, true);
            app.XtrDropDown.Value = 'X_train';
            app.YtrDropDownLabel = uilabel(app.GridLayout3);
            app.YtrDropDownLabel.Text = 'Ytr';
            app.YtrDropDown = uidropdown(app.GridLayout3);
            app.YtrDropDown.Items = {'X_train', 'Y_train', 'X_test', 'Y_test'};
            app.YtrDropDown.ValueChangedFcn = createCallbackFcn(app, @YtrDropDownValueChanged, true);
            app.YtrDropDown.Value = 'Y_train';
            app.ValCheckBox = uicheckbox(app.GridLayout3);
            app.ValCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValCheckBoxValueChanged, true);
            app.ValCheckBox.Text = 'Use validation data';
            app.XvalDropDown = uidropdown(app.GridLayout3);
            app.XvalDropDown.Items = {};
            app.XvalDropDown.Enable = 'off';
            app.XvalDropDown.Value = {};
            app.XvalLabel = uilabel(app.GridLayout3);
            app.XvalLabel.Enable = 'off';
            app.XvalLabel.Text = 'Xval';
            app.YvalLabel = uilabel(app.GridLayout3);
            app.YvalLabel.Enable = 'off';
            app.YvalLabel.Text = 'Yval';
            app.YvalDropDown = uidropdown(app.GridLayout3);
            app.YvalDropDown.Items = {};
            app.YvalDropDown.Enable = 'off';
            app.YvalDropDown.Value = {};
            app.TrainButton = uibutton(app.GridLayout3, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.Button = uibutton(app.GridLayout3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'triL.png');
            app.Button.Text = '';
            app.StopButton = uibutton(app.GridLayout3, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.Panel = uipanel(app.GridLayout3);
            app.infVal1 = uilabel(app.Panel);
            app.infVal1.Enable = 'off';
            app.infVal1.Text = 'Or use';
            app.infVal2 = uieditfield(app.Panel, 'numeric');
            app.infVal2.Enable = 'off';
            app.infVal3 = uilabel(app.Panel);
            app.infVal3.Enable = 'off';
            app.infVal3.Text = '% of Train data';
            app.Panel_2 = uipanel(app.GridLayout3);
            app.GridLayout8 = uigridlayout(app.Panel_2);
            app.UIAxes = uiaxes(app.GridLayout8);
            title(app.UIAxes, 'Learning Plot')
            xlabel(app.UIAxes, 'EPOCH')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout8);
            xlabel(app.UIAxes_2, 'EPOCH')
            zlabel(app.UIAxes_2, 'Z')
            app.TrainingSettingsPanel = uipanel(app.GridLayout3);
            app.TrainingSettingsPanel.Title = 'Training Settings';
            app.TrainingSettingsPanel.Visible = 'off';
            app.GridLayout4 = uigridlayout(app.TrainingSettingsPanel);
            app.alphaEditFieldLabel = uilabel(app.GridLayout4);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.GridLayout4, 'numeric');
            app.alphaEditField.Limits = [0 Inf];
            app.alphaEditField.Tooltip = {'learning rate'};
            app.alphaEditField.Value = 1;
            app.epochEditFieldLabel = uilabel(app.GridLayout4);
            app.epochEditFieldLabel.Text = 'epoch';
            app.epochEditField = uieditfield(app.GridLayout4, 'numeric');
            app.epochEditField.Limits = [1 Inf];
            app.epochEditField.Tooltip = {'number of maximum epoch'};
            app.epochEditField.Value = 200;
            app.LEditFieldLabel = uilabel(app.GridLayout4);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.GridLayout4, 'numeric');
            app.LEditField.Limits = [1 Inf];
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Tooltip = {'number of layer'};
            app.LEditField.Value = 2;
            app.AFEditFieldLabel = uilabel(app.GridLayout4);
            app.AFEditFieldLabel.Text = 'AF';
            app.AFEditField = uieditfield(app.GridLayout4, 'text');
            app.AFEditField.Value = '["sigmoid"]';
            app.NodesEditFieldLabel = uilabel(app.GridLayout4);
            app.NodesEditFieldLabel.Enable = 'off';
            app.NodesEditFieldLabel.Text = 'Nodes';
            app.NodesEditField = uieditfield(app.GridLayout4, 'text');
            app.NodesEditField.Enable = 'off';
            app.deltaEditFieldLabel = uilabel(app.GridLayout4);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.GridLayout4, 'numeric');
            app.deltaEditField.Limits = [0 Inf];
            app.deltaEditField.Tooltip = {'thereshold'};
            app.deltaEditField.Value = 0.0497870683678639;
            app.SolverDropDownLabel = uilabel(app.GridLayout4);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.GridLayout4);
            app.SolverDropDown.Items = {'SGD', 'MiniBatchGD', 'ADAM', 'BatchGD', 'BatchAdam'};
            app.SolverDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverDropDownValueChanged, true);
            app.SolverDropDown.Value = 'SGD';
            app.LRScheduleEditFieldLabel = uilabel(app.GridLayout4);
            app.LRScheduleEditFieldLabel.Text = 'LR-Schedule';
            app.LRScheduleEditField = uieditfield(app.GridLayout4, 'text');
            app.LRScheduleEditField.Tooltip = {'Default: "none"'; '[epoch,LR,...,epoch,LR]'};
            app.LRScheduleEditField.Value = '["none"]';
            app.lambdaLabel = uilabel(app.GridLayout4);
            app.lambdaLabel.Text = 'RegTem';
            app.RegTemEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PatienceEditFieldLabel = uilabel(app.GridLayout4);
            app.PatienceEditFieldLabel.Text = 'Patience';
            app.PatienceEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PatienceEditField.Value = Inf;
            app.MBEditFieldLabel = uilabel(app.GridLayout4);
            app.MBEditFieldLabel.Enable = 'off';
            app.MBEditFieldLabel.Text = 'MB';
            app.MBEditField = uieditfield(app.GridLayout4, 'numeric');
            app.MBEditField.Enable = 'off';
            app.MBEditField.Value = 1;
            app.v0EditFieldLabel = uilabel(app.GridLayout4);
            app.v0EditFieldLabel.Enable = 'off';
            app.v0EditFieldLabel.Text = 'v0';
            app.v0EditField = uieditfield(app.GridLayout4, 'numeric');
            app.v0EditField.Enable = 'off';
            app.m0EditFieldLabel = uilabel(app.GridLayout4);
            app.m0EditFieldLabel.Enable = 'off';
            app.m0EditFieldLabel.Text = 'm0';
            app.m0EditField = uieditfield(app.GridLayout4, 'numeric');
            app.m0EditField.Enable = 'off';
            app.b2EditFieldLabel = uilabel(app.GridLayout4);
            app.b2EditFieldLabel.Enable = 'off';
            app.b2EditFieldLabel.Text = 'b2';
            app.b2EditField = uieditfield(app.GridLayout4, 'numeric');
            app.b2EditField.Enable = 'off';
            app.b2EditField.Value = 0.999;
            app.b1EditFieldLabel = uilabel(app.GridLayout4);
            app.b1EditFieldLabel.Enable = 'off';
            app.b1EditFieldLabel.Text = 'b1';
            app.b1EditField = uieditfield(app.GridLayout4, 'numeric');
            app.b1EditField.Enable = 'off';
            app.b1EditField.Value = 0.9;
            app.ValFreqEditFieldLabel = uilabel(app.GridLayout4);
            app.ValFreqEditFieldLabel.Enable = 'off';
            app.ValFreqEditFieldLabel.Text = 'ValFreq';
            app.ValFreqEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ValFreqEditField.Enable = 'off';
            app.ValFreqEditField.Value = 1;
            app.RulePruningTab = uitab(app.TabGroup2);
            app.RulePruningTab.Title = 'Rule Pruning';
            app.GridLayout5 = uigridlayout(app.RulePruningTab);
            app.LoadNetDropDown_2Label = uilabel(app.GridLayout5);
            app.LoadNetDropDown_2Label.Enable = 'off';
            app.LoadNetDropDown_2Label.Text = 'Load Net';
            app.LoadNet_RulePrun = uidropdown(app.GridLayout5);
            app.LoadNet_RulePrun.Items = {'LastTrainedNet', 'BestTrainedNet'};
            app.LoadNet_RulePrun.Enable = 'off';
            app.LoadNet_RulePrun.Value = 'LastTrainedNet';
            app.PruneRulesButton = uibutton(app.GridLayout5, 'push');
            app.PruneRulesButton.Enable = 'off';
            app.PruneRulesButton.Text = 'Prune Rules';
            app.ComingSoonLabel = uilabel(app.GridLayout5);
            app.ComingSoonLabel.Interpreter = 'latex';
            app.ComingSoonLabel.Text = 'Coming Soon ...';
            app.TestTab_2 = uitab(app.TabGroup2);
            app.TestTab_2.Title = 'Test';
            app.GridLayout6 = uigridlayout(app.TestTab_2);
            app.TabGroup3 = uitabgroup(app.GridLayout6);
            app.Test1Tab = uitab(app.TabGroup3);
            app.Test1Tab.Title = 'Test 1';
            app.GridLayout7 = uigridlayout(app.Test1Tab);
            app.UIAxesTest1 = uiaxes(app.GridLayout7);
            title(app.UIAxesTest1, 'Test Result')
            xlabel(app.UIAxesTest1, 'X')
            ylabel(app.UIAxesTest1, 'Y')
            zlabel(app.UIAxesTest1, 'Z')
            app.UIAxesTest1.Visible = 'off';
            app.LoadNetDropDownLabel = uilabel(app.GridLayout7);
            app.LoadNetDropDownLabel.Text = 'Load Net';
            app.LoadNetDropDown = uidropdown(app.GridLayout7);
            app.LoadNetDropDown.Items = {'LastTrainedNet', 'BestTrainedNet'};
            app.LoadNetDropDown.Value = 'LastTrainedNet';
            app.XteDropDownLabel = uilabel(app.GridLayout7);
            app.XteDropDownLabel.Text = 'Xte';
            app.XteDropDown = uidropdown(app.GridLayout7);
            app.XteDropDown.Items = {'X_train', 'Y_train', 'X_test', 'Y_test'};
            app.XteDropDown.Value = 'X_test';
            app.YteDropDownLabel = uilabel(app.GridLayout7);
            app.YteDropDownLabel.Text = 'Yte';
            app.YteDropDown = uidropdown(app.GridLayout7);
            app.YteDropDown.Items = {'X_train', 'Y_train', 'X_test', 'Y_test'};
            app.YteDropDown.Value = 'Y_test';
            app.TestButton = uibutton(app.GridLayout7, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.Label_3 = uilabel(app.GridLayout7);
            app.Button_2 = uibutton(app.GridLayout6, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_2.Icon = fullfile(pathToMLAPP, 'app', 'plus.png');
            app.SOMFNNLabel = uilabel(app.GridLayout9);
            app.SOMFNNLabel.Interpreter = 'tex';
            app.SOMFNNLabel.Text = 'SOMFNN';
            app.PoweredbyITHENOAv2Label = uilabel(app.GridLayout9);
            app.PoweredbyITHENOAv2Label.Text = 'Powered by ITHENOA   v2       ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SOMFNN_Application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end